classdef super_kes_klikac < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes_1                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        Button_back               matlab.ui.control.Button
        Button_froward            matlab.ui.control.Button
        savesButton               matlab.ui.control.Button
        curent_numEditFieldLabel  matlab.ui.control.Label
        curent_numEditField       matlab.ui.control.NumericEditField
        showoutofEditField        matlab.ui.control.EditField
        filenameEditFieldLabel    matlab.ui.control.Label
        filenameEditField         matlab.ui.control.EditField
    end

    
    properties (Access = public)
        signal_names
        heatmap_names
        signal_num
        curent_num
        signal
        heatmap
        s1
        s2
        s3
        result_positions
        p1
        p2
        p3
        p4
        result_name
    end
    
    methods (Access = public)
        
        function reload_data(app)
            app.signal=load(app.signal_names{app.curent_num});
            app.signal=app.signal.val;
            app.heatmap=readNPY(app.heatmap_names{app.curent_num});
            
            app.showoutofEditField.Value=[num2str(app.curent_num) '/' num2str(app.signal_num)];
            app.curent_numEditField.Value=app.curent_num;
            
            
            [~,name,~] = fileparts(app.signal_names{app.curent_num});
            app.filenameEditField.Value=name;
            
            heatmap_name=app.heatmap_names{app.curent_num};
            app.result_name=replace(heatmap_name,'_heatmap.npy','manual.mat');
            
        
            plot(app.UIAxes_1,app.signal(app.s1,:));
            plot(app.UIAxes_2,app.signal(app.s2,:));
            plot(app.UIAxes_3,app.signal(app.s3,:));
            
            plot(app.UIAxes_4,app.heatmap);


            if isfile(app.result_name)
                
                tmp=load(app.result_name);
                app.result_positions=tmp.result_positionss;
                
                if ~isempty(app.result_positions)
                
                    hold(app.UIAxes_1, 'on')
                    hold(app.UIAxes_2, 'on')
                    hold(app.UIAxes_3, 'on')
                    hold(app.UIAxes_4, 'on')
                    
                    app.p1=plot(app.UIAxes_1,app.result_positions,app.signal(app.s1,round(app.result_positions)),'r*');
                    app.p2=plot(app.UIAxes_2,app.result_positions,app.signal(app.s2,round(app.result_positions)),'r*');
                    app.p3=plot(app.UIAxes_3,app.result_positions,app.signal(app.s3,round(app.result_positions)),'r*');
                    
                    app.p4=plot(app.UIAxes_4,app.result_positions,app.heatmap(round(app.result_positions)),'r*');
                    
                    hold(app.UIAxes_1, 'off')
                    hold(app.UIAxes_2, 'off')
                    hold(app.UIAxes_3, 'off')
                    hold(app.UIAxes_4, 'off')
                else
                    app.p1=999;
                    
                end
                
            
            else
                app.result_positions=[];
                app.p1=999;
            end


        end
        
        function save_res(app)
            result_positionss=app.result_positions;
            save(app.result_name,'result_positionss')
            

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, signal_names, heatmap_names)
            app.signal_names=signal_names;
            app.heatmap_names=heatmap_names;
            app.signal_num=length(app.signal_names);
            app.curent_num=1;
            
            app.s1=1;
            app.s2=2;
            app.s3=3;
            
            
            addpath('npy-matlab') 
            
            
            app.reload_data()
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)

            
            position=round(event.Source.CurrentAxes.CurrentPoint(1,[2,1]));
            
            position=position(2);
            position(position<1)=1;
            position(position>size(app.signal,2))=size(app.signal,2);
            
            if strcmp(event.Source.SelectionType,'normal')
            
                app.result_positions=[app.result_positions,position];
                
                if app.p1==999
                    hold(app.UIAxes_1, 'on')
                    hold(app.UIAxes_2, 'on')
                    hold(app.UIAxes_3, 'on')
                    hold(app.UIAxes_4, 'on')
                        
                    app.p1=plot(app.UIAxes_1,app.result_positions,app.signal(app.s1,round(app.result_positions)),'r*');
                    app.p2=plot(app.UIAxes_2,app.result_positions,app.signal(app.s2,round(app.result_positions)),'r*');
                    app.p3=plot(app.UIAxes_3,app.result_positions,app.signal(app.s3,round(app.result_positions)),'r*');
                    
                    app.p4=plot(app.UIAxes_4,app.result_positions,app.heatmap(round(app.result_positions)),'r*');
                    
                    hold(app.UIAxes_1, 'off')
                    hold(app.UIAxes_2, 'off')
                    hold(app.UIAxes_3, 'off')
                    hold(app.UIAxes_4, 'off')
                    
                end

                drawnow
            
            elseif strcmp(event.Source.SelectionType,'alt')
                
                drawnow;
                
                dists=abs(app.result_positions-position);
                
                [dist,min_dist_arg]=min(dists);
                if dist<500
                    app.result_positions(min_dist_arg)=[];
                end
                
                
            end
            
                app.p1.XData=app.result_positions;
                app.p1.YData=app.signal(app.s1,round(app.result_positions));
                app.p2.XData=app.result_positions;
                app.p2.YData=app.signal(app.s2,round(app.result_positions));
                app.p3.XData=app.result_positions;
                app.p3.YData=app.signal(app.s3,round(app.result_positions));
                app.p4.XData=app.result_positions;
                app.p4.YData=app.heatmap(round(app.result_positions));

        end

        % Button pushed function: savesButton
        function savesButtonPushed(app, event)
            app.save_res()
        end

        % Button pushed function: Button_back
        function Button_backPushed(app, event)
            app.save_res()
            app.curent_num=app.curent_num-1;
            if app.curent_num==0
                app.curent_num=app.signal_num;
            end
            app.reload_data()
        end

        % Button pushed function: Button_froward
        function Button_frowardPushed(app, event)
            app.save_res()
            app.curent_num=app.curent_num+1;
            if app.curent_num==(app.signal_num+1)
                app.curent_num=1;
            end
            app.reload_data()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes_1 = uiaxes(app.UIFigure);
            title(app.UIAxes_1, 'Title')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.Button_back = uibutton(app.UIFigure, 'push');
            app.Button_back.ButtonPushedFcn = createCallbackFcn(app, @Button_backPushed, true);
            app.Button_back.Text = '<';
            app.Button_froward = uibutton(app.UIFigure, 'push');
            app.Button_froward.ButtonPushedFcn = createCallbackFcn(app, @Button_frowardPushed, true);
            app.Button_froward.Text = '>';
            app.savesButton = uibutton(app.UIFigure, 'push');
            app.savesButton.ButtonPushedFcn = createCallbackFcn(app, @savesButtonPushed, true);
            app.savesButton.Text = 'save (s)';
            app.curent_numEditFieldLabel = uilabel(app.UIFigure);
            app.curent_numEditFieldLabel.Text = 'curent_num';
            app.curent_numEditField = uieditfield(app.UIFigure, 'numeric');
            app.curent_numEditField.Value = 1;
            app.showoutofEditField = uieditfield(app.UIFigure, 'text');
            app.showoutofEditField.Editable = 'off';
            app.showoutofEditField.Value = 'x';
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'filename';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = super_kes_klikac(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end